classdef deverpapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        FilterMenu                      matlab.ui.container.Menu
        filtersinglesubject             matlab.ui.container.Menu
        filtermultisubject              matlab.ui.container.Menu
        LinenoiseMenu                   matlab.ui.container.Menu
        linenoisesinglesubject          matlab.ui.container.Menu
        linenoisemultisubject           matlab.ui.container.Menu
        RerefMenu                       matlab.ui.container.Menu
        rerefsinglesubject              matlab.ui.container.Menu
        rerefmultisubject               matlab.ui.container.Menu
        ChannelRejectionMenu            matlab.ui.container.Menu
        channelrejectionsinglesubject   matlab.ui.container.Menu
        channelrejectionmultisubject    matlab.ui.container.Menu
        ASRMenu                         matlab.ui.container.Menu
        asrsinglesubject                matlab.ui.container.Menu
        asrmultisubject                 matlab.ui.container.Menu
        ArtifactRejectionMenu           matlab.ui.container.Menu
        artifactrejectionsinglesubject  matlab.ui.container.Menu
        artifactrejectionmultisubject   matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ArtifactrejectionautoSingleSubjectPanel  matlab.ui.container.Panel
        RunButton_6                     matlab.ui.control.Button
        ListBox_6                       matlab.ui.control.ListBox
        ASRSingleSubjectPanel           matlab.ui.container.Panel
        RunButton_5                     matlab.ui.control.Button
        ListBox_5                       matlab.ui.control.ListBox
        ChannelRejectionautoSingleSubjectPanel  matlab.ui.container.Panel
        RunButton_4                     matlab.ui.control.Button
        ListBox_4                       matlab.ui.control.ListBox
        RerefSingleSubjectPanel         matlab.ui.container.Panel
        RunButton_3                     matlab.ui.control.Button
        ListBox_3                       matlab.ui.control.ListBox
        LinenoiseSingleSubjectPanel     matlab.ui.container.Panel
        RunButton_2                     matlab.ui.control.Button
        ListBox_2                       matlab.ui.control.ListBox
        FilterSingleSubjectPanel        matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        Run2Button                      matlab.ui.control.Button
        Run1Button                      matlab.ui.control.Button
        RunningLabel                    matlab.ui.control.Label
        ArtifactRejectionButton         matlab.ui.control.Button
        ChannelRejectionButton          matlab.ui.control.Button
        WhichanalysisLabel              matlab.ui.control.Label
        subjectlistsearch               matlab.ui.control.Button
        subjectlistEditField            matlab.ui.control.EditField
        subjectlistEditFieldLabel       matlab.ui.control.Label
        SubjectListLabel                matlab.ui.control.Label
        txtdirsearch                    matlab.ui.control.Button
        erpdirsearch                    matlab.ui.control.Button
        workdirsearch                   matlab.ui.control.Button
        rawdirsearch                    matlab.ui.control.Button
        txtdirstr                       matlab.ui.control.EditField
        txtdirEditFieldLabel            matlab.ui.control.Label
        workdirstr                      matlab.ui.control.EditField
        workdirEditField_3Label         matlab.ui.control.Label
        erpdirstr                       matlab.ui.control.EditField
        erpdirEditFieldLabel            matlab.ui.control.Label
        rawdirstr                       matlab.ui.control.EditField
        rawdirEditFieldLabel            matlab.ui.control.Label
        DirectoriesLabel                matlab.ui.control.Label
        WelcometoDevERPAppDERPALabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: rawdirsearch
        function rawdirsearchButtonPushed(app, event)
            rawdir = uigetdir;
            assignin('base', 'rawdir', rawdir)
        end

        % Callback function: rawdirstr, rawdirstr
        function rawdirstrValueChanging(app, event)
           rawdir = event.Value;
           assignin('base', 'rawdir', rawdir)
        end

        % Button pushed function: workdirsearch
        function workdirsearchButtonPushed(app, event)
            workdir = uigetdir;
            assignin('base', 'workdir', workdir)
        end

        % Value changing function: workdirstr
        function workdirstrValueChanging(app, event)
            workdir = event.Value;
            assignin('base', 'workdir', workdir)
        end

        % Button pushed function: erpdirsearch
        function erpdirsearchButtonPushed(app, event)
            erpdir = uigetdir;
            assignin('base', 'erpdir', erpdir)            
        end

        % Value changing function: erpdirstr
        function erpdirstrValueChanging(app, event)
            erpdir = event.Value;
            assignin('base', 'erpdir', erpdir) 
        end

        % Button pushed function: txtdirsearch
        function txtdirsearchButtonPushed(app, event)
            txtdir = uigetdir;
            assignin('base', 'txtdir', txtdir)              
        end

        % Value changing function: txtdirstr
        function txtdirstrValueChanging(app, event)
            txtdir = event.Value;
            assignin('base', 'txtdir', txtdir) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.FilterMenu = uimenu(app.ToolsMenu);
            app.FilterMenu.Text = 'Filter';
            app.filtersinglesubject = uimenu(app.FilterMenu);
            app.filtersinglesubject.Text = 'Single Subject';
            app.filtermultisubject = uimenu(app.FilterMenu);
            app.filtermultisubject.Text = 'Multi Subject';
            app.LinenoiseMenu = uimenu(app.ToolsMenu);
            app.LinenoiseMenu.Text = 'Line noise';
            app.linenoisesinglesubject = uimenu(app.LinenoiseMenu);
            app.linenoisesinglesubject.Text = 'Single Subject';
            app.linenoisemultisubject = uimenu(app.LinenoiseMenu);
            app.linenoisemultisubject.Text = 'Multi Subject';
            app.RerefMenu = uimenu(app.ToolsMenu);
            app.RerefMenu.Text = 'Reref';
            app.rerefsinglesubject = uimenu(app.RerefMenu);
            app.rerefsinglesubject.Text = 'Single Subject';
            app.rerefmultisubject = uimenu(app.RerefMenu);
            app.rerefmultisubject.Text = 'Multi Subject';
            app.ChannelRejectionMenu = uimenu(app.ToolsMenu);
            app.ChannelRejectionMenu.Text = 'Channel Rejection (auto)';
            app.channelrejectionsinglesubject = uimenu(app.ChannelRejectionMenu);
            app.channelrejectionsinglesubject.Text = 'Single Subject';
            app.channelrejectionmultisubject = uimenu(app.ChannelRejectionMenu);
            app.channelrejectionmultisubject.Text = 'Multi Subject';
            app.ASRMenu = uimenu(app.ToolsMenu);
            app.ASRMenu.Text = 'ASR';
            app.asrsinglesubject = uimenu(app.ASRMenu);
            app.asrsinglesubject.Text = 'Single Subject';
            app.asrmultisubject = uimenu(app.ASRMenu);
            app.asrmultisubject.Text = 'Multi Subject';
            app.ArtifactRejectionMenu = uimenu(app.ToolsMenu);
            app.ArtifactRejectionMenu.Text = 'Artifact Rejection (auto)';
            app.artifactrejectionsinglesubject = uimenu(app.ArtifactRejectionMenu);
            app.artifactrejectionsinglesubject.Text = 'Single Subject';
            app.artifactrejectionmultisubject = uimenu(app.ArtifactRejectionMenu);
            app.artifactrejectionmultisubject.Text = 'Multi Subject';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help!';
            app.WelcometoDevERPAppDERPALabel = uilabel(app.UIFigure);
            app.WelcometoDevERPAppDERPALabel.Text = 'Welcome to DevERP-App (DERPA)!';
            app.DirectoriesLabel = uilabel(app.UIFigure);
            app.DirectoriesLabel.Text = 'Directories';
            app.rawdirEditFieldLabel = uilabel(app.UIFigure);
            app.rawdirEditFieldLabel.Text = 'rawdir';
            app.rawdirstr = uieditfield(app.UIFigure, 'text');
            app.rawdirstr.ValueChangedFcn = createCallbackFcn(app, @rawdirstrValueChanging, true);
            app.rawdirstr.ValueChangingFcn = createCallbackFcn(app, @rawdirstrValueChanging, true);
            app.erpdirEditFieldLabel = uilabel(app.UIFigure);
            app.erpdirEditFieldLabel.Text = 'erpdir';
            app.erpdirstr = uieditfield(app.UIFigure, 'text');
            app.erpdirstr.ValueChangingFcn = createCallbackFcn(app, @erpdirstrValueChanging, true);
            app.workdirEditField_3Label = uilabel(app.UIFigure);
            app.workdirEditField_3Label.Text = 'workdir';
            app.workdirstr = uieditfield(app.UIFigure, 'text');
            app.workdirstr.ValueChangingFcn = createCallbackFcn(app, @workdirstrValueChanging, true);
            app.txtdirEditFieldLabel = uilabel(app.UIFigure);
            app.txtdirEditFieldLabel.Text = 'txtdir';
            app.txtdirstr = uieditfield(app.UIFigure, 'text');
            app.txtdirstr.ValueChangingFcn = createCallbackFcn(app, @txtdirstrValueChanging, true);
            app.rawdirsearch = uibutton(app.UIFigure, 'push');
            app.rawdirsearch.ButtonPushedFcn = createCallbackFcn(app, @rawdirsearchButtonPushed, true);
            app.rawdirsearch.Text = 'Search';
            app.workdirsearch = uibutton(app.UIFigure, 'push');
            app.workdirsearch.ButtonPushedFcn = createCallbackFcn(app, @workdirsearchButtonPushed, true);
            app.workdirsearch.Text = 'Search';
            app.erpdirsearch = uibutton(app.UIFigure, 'push');
            app.erpdirsearch.ButtonPushedFcn = createCallbackFcn(app, @erpdirsearchButtonPushed, true);
            app.erpdirsearch.Text = 'Search';
            app.txtdirsearch = uibutton(app.UIFigure, 'push');
            app.txtdirsearch.ButtonPushedFcn = createCallbackFcn(app, @txtdirsearchButtonPushed, true);
            app.txtdirsearch.Text = 'Search';
            app.SubjectListLabel = uilabel(app.UIFigure);
            app.SubjectListLabel.Text = 'Subject List';
            app.subjectlistEditFieldLabel = uilabel(app.UIFigure);
            app.subjectlistEditFieldLabel.Text = 'subjectlist';
            app.subjectlistEditField = uieditfield(app.UIFigure, 'text');
            app.subjectlistsearch = uibutton(app.UIFigure, 'push');
            app.subjectlistsearch.Text = 'Search';
            app.WhichanalysisLabel = uilabel(app.UIFigure);
            app.WhichanalysisLabel.Text = 'Which analysis?';
            app.ChannelRejectionButton = uibutton(app.UIFigure, 'push');
            app.ChannelRejectionButton.Text = 'Channel Rejection (man)';
            app.ArtifactRejectionButton = uibutton(app.UIFigure, 'push');
            app.ArtifactRejectionButton.Text = 'Artifact Rejection (man)';
            app.RunningLabel = uilabel(app.UIFigure);
            app.RunningLabel.Text = 'Running';
            app.Run1Button = uibutton(app.UIFigure, 'push');
            app.Run1Button.Text = 'Run 1';
            app.Run2Button = uibutton(app.UIFigure, 'push');
            app.Run2Button.Text = 'Run 2';
            app.FilterSingleSubjectPanel = uipanel(app.UIFigure);
            app.FilterSingleSubjectPanel.Title = 'Filter Single Subject';
            app.ListBox = uilistbox(app.FilterSingleSubjectPanel);
            app.RunButton = uibutton(app.FilterSingleSubjectPanel, 'push');
            app.RunButton.Text = 'Run';
            app.LinenoiseSingleSubjectPanel = uipanel(app.UIFigure);
            app.LinenoiseSingleSubjectPanel.Title = 'Line noise Single Subject';
            app.ListBox_2 = uilistbox(app.LinenoiseSingleSubjectPanel);
            app.RunButton_2 = uibutton(app.LinenoiseSingleSubjectPanel, 'push');
            app.RunButton_2.Text = 'Run';
            app.RerefSingleSubjectPanel = uipanel(app.UIFigure);
            app.RerefSingleSubjectPanel.Title = 'Reref Single Subject';
            app.ListBox_3 = uilistbox(app.RerefSingleSubjectPanel);
            app.RunButton_3 = uibutton(app.RerefSingleSubjectPanel, 'push');
            app.RunButton_3.Text = 'Run';
            app.ChannelRejectionautoSingleSubjectPanel = uipanel(app.UIFigure);
            app.ChannelRejectionautoSingleSubjectPanel.Title = 'Channel Rejection (auto) Single Subject';
            app.ListBox_4 = uilistbox(app.ChannelRejectionautoSingleSubjectPanel);
            app.RunButton_4 = uibutton(app.ChannelRejectionautoSingleSubjectPanel, 'push');
            app.RunButton_4.Text = 'Run';
            app.ASRSingleSubjectPanel = uipanel(app.UIFigure);
            app.ASRSingleSubjectPanel.Title = 'ASR Single Subject';
            app.ListBox_5 = uilistbox(app.ASRSingleSubjectPanel);
            app.RunButton_5 = uibutton(app.ASRSingleSubjectPanel, 'push');
            app.RunButton_5.Text = 'Run';
            app.ArtifactrejectionautoSingleSubjectPanel = uipanel(app.UIFigure);
            app.ArtifactrejectionautoSingleSubjectPanel.Title = 'Artifact rejection (auto) Single Subject';
            app.ListBox_6 = uilistbox(app.ArtifactrejectionautoSingleSubjectPanel);
            app.RunButton_6 = uibutton(app.ArtifactrejectionautoSingleSubjectPanel, 'push');
            app.RunButton_6.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deverpapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end